classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BinarizeButton         matlab.ui.control.Button
        RecognizeButton        matlab.ui.control.Button
        OutputEditField        matlab.ui.control.EditField
        OutputEditFieldLabel   matlab.ui.control.Label
        LoadimageButton        matlab.ui.control.Button
        ConverttoarabicButton  matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        imageFile; % Description
        dict = ['C'; 'D';'I';'L'; 'M';'V';'X'];
        nn = load ('ocr_final_8_new.mat'); % siec neuronowa
         fun = {@AO5RBlairBliss, @AO5RCircularityL, @AO5RCircularityS, @AO5RShape, @AO5RDanielsson, @AO5RFeret, @AO5RHaralick, @AO5RMalinowska};
        wsp_train = {@wspolczynniki_train};
        bim; % binarny obraz
    end

    methods (Access = private)
        
        %binaryzacja
        function [gray_im,bim] = bin_im(app)
            app.bim = ~imbinarize(app.imageFile,'adaptive','ForegroundPolarity','dark','Sensitivity',0.1);
            app.bim = app.bim(:,:,1);
            app.bim = imclose(app.bim,strel('disk',10));
            app.bim = imopen(app.bim,ones(5));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function loadImage(app, event)
           
            %{
[filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           app.Image.ImageSource = fullname;
            %}
          
            [filename, filepath] = uigetfile({'*.*';'*.jpg';'*.png';'*.bmp'}, 'Choose file to load');
            figure(app.UIFigure)
            FullFileName = [filepath filename];
          
            %load image if file selected
            if ~isequal(filename,0)
                app.imageFile = double(imread(FullFileName))/255;
                imshow(app.imageFile,'parent',app.UIAxes);
                app.OutputEditField.Value = '';

        end
        end

        % Button pushed function: ConverttoarabicButton
        function converToArabic(app, event)
            app.bim = ~imbinarize(app.imageFile,'adaptive','ForegroundPolarity','dark','Sensitivity',0.1);
            app.bim = app.bim(:,:,1);
            app.bim = imclose(app.bim,strel('disk',10));
            app.bim = imopen(app.bim,ones(5));

            test=app.bim;
            test = test(:,:,1);
            test = imopen(test,ones(2));
            a = regionprops(test,'Image');
            wtest = zeros(length(a), length(app.fun));
            for i = 1:length(a)
                for j = 1:length(app.fun)
                    binary_image = a(i).Image;
                    binary_image = padarray(binary_image,0);
                    binary_image = imresize(binary_image, [300, 300]);
                    wtest(i,j) =  app.fun{j}(binary_image);
                end
            end
            out1 = app.nn.nn(wtest');
            result = '';
            for i = 1:1:size(out1,2)
                result = strcat(result,string(app.dict(find(out1(:,i) == max(out1(:,i))))));
            end

            s=result;
            decimal = [1000, 500, 100, 50, 10, 5, 1];
    roman = ['M', 'D', 'C', 'L', 'X', 'V', 'I'];
    tokens = arrayfun(@(x) decimal(find(roman==x,1)), char(s));
    num = tokens(end);
    for idx = 1:numel(tokens)-1
        val = tokens(idx);
        ridx = find(tokens(idx+1:end) ~= val, 1);
        if ~isempty(ridx) && tokens(ridx + idx) > val
            num = num - val;
        else
            num = num + val;
        end
    end
    app.OutputEditField.Value =int2str(num);
    
        end

        % Button pushed function: RecognizeButton
        function RecognizeButtonPushed(app, event)
            %test = ~imbinarize(app.imageFile,0.8);
            app.bim = ~imbinarize(app.imageFile,'adaptive','ForegroundPolarity','dark','Sensitivity',0.1);
            app.bim = app.bim(:,:,1);
            app.bim = imclose(app.bim,strel('disk',10));
            app.bim = imopen(app.bim,ones(5));

            test=app.bim;
            test = test(:,:,1);
            test = imopen(test,ones(2));
            a = regionprops(test,'Image');
            wtest = zeros(length(a), length(app.fun));

             for i = 1:length(a)
                for j = 1:length(app.fun)
                    binary_image = a(i).Image;
                    binary_image = padarray(binary_image,0);
                    binary_image = imresize(binary_image, [300, 300]);
                    wtest(i,j) =  app.fun{j}(binary_image);
                end
            end
            out1 = app.nn.nn(wtest');
            result = '';
            for i = 1:1:size(out1,2)
                result = strcat(result,string(app.dict(find(out1(:,i) == max(out1(:,i))))));
            end
            app.OutputEditField.Value = result;


        end

        % Button pushed function: BinarizeButton
        function BinarizeButtonPushed(app, event)
            bin_im(app);
            imshow(app.bim,'parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ConverttoarabicButton = uibutton(app.UIFigure, 'push');
            app.ConverttoarabicButton.ButtonPushedFcn = createCallbackFcn(app, @converToArabic, true);
            app.ConverttoarabicButton.Text = 'Convert to arabic';
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @loadImage, true);
            app.LoadimageButton.Text = 'Load image';
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'text');
            app.RecognizeButton = uibutton(app.UIFigure, 'push');
            app.RecognizeButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizeButtonPushed, true);
            app.RecognizeButton.Text = 'Recognize';
            app.BinarizeButton = uibutton(app.UIFigure, 'push');
            app.BinarizeButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizeButtonPushed, true);
            app.BinarizeButton.Text = 'Binarize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end